classdef SoundAnalysisV6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SoundAnalysisUIFigure           matlab.ui.Figure
        LoadDataButton                  matlab.ui.control.Button
        MinFrequencyHZEditFieldLabel    matlab.ui.control.Label
        MinFrequencyHZEditField         matlab.ui.control.NumericEditField
        MaxFrequencyHZEditFieldLabel    matlab.ui.control.Label
        MaxFrequencyHZEditField         matlab.ui.control.NumericEditField
        SampleTimesecEditFieldLabel     matlab.ui.control.Label
        SampleTimesecEditField          matlab.ui.control.NumericEditField
        StepTimesecEditFieldLabel       matlab.ui.control.Label
        StepTimesecEditField            matlab.ui.control.NumericEditField
        BackgroundThresEditFieldLabel   matlab.ui.control.Label
        BackgroundThresEditField        matlab.ui.control.NumericEditField
        TabGroup                        matlab.ui.container.TabGroup
        SpectrumTab                     matlab.ui.container.Tab
        SpectrumAxes                    matlab.ui.control.UIAxes
        BackgroundTab                   matlab.ui.container.Tab
        BackgroundAxes                  matlab.ui.control.UIAxes
        NormalizedspectrumTab           matlab.ui.container.Tab
        NormalizedSpectrumAxes          matlab.ui.control.UIAxes
        TrackingTab                     matlab.ui.container.Tab
        TrackingAxes                    matlab.ui.control.UIAxes
        Statues                         matlab.ui.control.Label
        SearchingButton                 matlab.ui.control.Button
        SaveresultButton                matlab.ui.control.Button
        ParameterfilenameEditFieldLabel  matlab.ui.control.Label
        ParameterfilenameEditField      matlab.ui.control.EditField
        AmplitudefilenameEditFieldLabel  matlab.ui.control.Label
        AmplitudefilenameEditField      matlab.ui.control.EditField
        CropAudioButton                 matlab.ui.control.Button
        CroppingstarttimesEditFieldLabel  matlab.ui.control.Label
        CroppingstarttimesEditField     matlab.ui.control.NumericEditField
        CroppingendtimesEditFieldLabel  matlab.ui.control.Label
        CroppingendtimesEditField       matlab.ui.control.NumericEditField
        AudiofilenameEditFieldLabel     matlab.ui.control.Label
        AudiofilenameEditField          matlab.ui.control.EditField
        BoutselectionSpinnerLabel       matlab.ui.control.Label
        BoutselectionSpinner            matlab.ui.control.Spinner
        MainParametersLabel             matlab.ui.control.Label
        SavexlsxLabel                   matlab.ui.control.Label
        SaveCroppedaudioLabel           matlab.ui.control.Label
        ProcessbuttonLabel              matlab.ui.control.Label
        FiguresLabel                    matlab.ui.control.Label
        FindBackgroundButton            matlab.ui.control.Button
        OtherParametersLabel            matlab.ui.control.Label
        BackgroundshirinkagesEditFieldLabel  matlab.ui.control.Label
        BackgroundshirinkagesEditField  matlab.ui.control.NumericEditField
        ShortestsoundboutsEditFieldLabel  matlab.ui.control.Label
        ShortestsoundboutsEditField     matlab.ui.control.NumericEditField
        FundamentalmeanZscorethresholdEditFieldLabel  matlab.ui.control.Label
        FundamentalmeanZscorethresholdEditField  matlab.ui.control.NumericEditField
        SignalremovalGaussianfilterfrequencyEditFieldLabel  matlab.ui.control.Label
        SignalremovalGaussianfilterfrequencyEditField  matlab.ui.control.NumericEditField
        SignalremovalGaussianfiltertimeEditFieldLabel  matlab.ui.control.Label
        SignalremovalGaussianfiltertimeEditField  matlab.ui.control.NumericEditField
        OtherpeaksmeanZscorethresholdEditFieldLabel  matlab.ui.control.Label
        OtherpeaksmeanZscorethresholdEditField  matlab.ui.control.NumericEditField
        ResetButton                     matlab.ui.control.Button
        RemoveintervalsmallerthansEditFieldLabel  matlab.ui.control.Label
        RemoveintervalsmallerthansEditField  matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        SpectrumAlongTime % original spectrum
        I %plot original spectrum
        RMS % RMS spectrum
        SpectrumAlongTime_normalize % normalized spectrum
        t % used to draw image axis
        parameters % result 1
        RMSEachPeak %re result 2
        y
        Fs
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %% statue
            app.Statues.Text="Busy: load data";
            app.Statues.FontColor=[0,0,0];
            
            %% add function
            addpath("D:\Work\project\Kenichi\SoundAnalysis\V6\functions");
            
            %% reset axes
            cla(app.SpectrumAxes,'reset')
            cla(app.BackgroundAxes,'reset')
            cla(app.NormalizedSpectrumAxes,'reset')
            cla(app.TrackingAxes,'reset')
            
            app.MaxFrequencyHZEditField.Editable='off';
            app.MinFrequencyHZEditField.Editable='off';
            app.SampleTimesecEditField.Editable='off';
            app.StepTimesecEditField.Editable='off';
            
            %% get the path of wav file
            [filename,pathname] = uigetfile('.wav');
            
            %% focus the ui again
            drawnow;
            figure(app.SoundAnalysisUIFigure);
            if(filename==0)
                %% statue
                app.Statues.Text="Ready";
                app.Statues.FontColor=[0,0,0];
                return;
            end
            
            %% add path and read the file
            addpath(pathname);
            cd(pathname);
            [app.y,app.Fs] = audioread(filename);
            T = 1/app.Fs;             % Sampling period       
            L = length(app.y);        % Length of signal
            time = (0:L-1)*T;        % Time vector
            
            %% Get Parameters
            min_HZ=app.MinFrequencyHZEditField.Value;
            max_HZ=app.MaxFrequencyHZEditField.Value;
            sampleTime=app.SampleTimesecEditField.Value;
            stepTime=app.StepTimesecEditField.Value;
            Background_shrink_time=app.BackgroundshirinkagesEditField.Value;
            global Background_shrink;
            Background_shrink=round(Background_shrink_time/stepTime);
            %% Get spectrum
            app.Statues.Text="Busy: get spectrum";
            pause(0.1);
            app.SpectrumAlongTime=getSpectrumTime(app.y,app.Fs,min_HZ,max_HZ,sampleTime,stepTime);
            app.RMS=Orig2RMS(app.SpectrumAlongTime);
            app.t=(1:size(app.SpectrumAlongTime,2))*time(end)/size(app.SpectrumAlongTime,2);
            
            %% plot original spectrum
            app.I = imagesc(mat2gray(flipud(app.SpectrumAlongTime)), 'Parent', app.SpectrumAxes, ...
        'XData', [1 app.SpectrumAxes.Position(3)], ...
        'YData', [1 app.SpectrumAxes.Position(4)]);
            
            app.SpectrumAxes.XLim = [0 app.I.XData(2)];
            app.SpectrumAxes.YLim = [0 app.I.YData(2)];
            
            Xticklabels_ruler=0:app.t(end)/10:app.t(end);
            app.SpectrumAxes.XTick = linspace(1, app.I.XData(2), numel(Xticklabels_ruler));
            app.SpectrumAxes.XTickLabel=Xticklabels_ruler;
            app.SpectrumAxes.YTick = linspace(1, app.I.YData(2), 11);
            app.SpectrumAxes.YTickLabel=max_HZ:-(max_HZ)/10:0;
            
            %% search background
            FindBackgroundButtonPushed(app, event)
        end

        % Button pushed function: SearchingButton
        function SearchingButtonPushed(app, event)
            %%
            app.Statues.Text="Busy";
            app.Statues.FontColor=[0,0,0];
            
            pause(0.1);
            %% Get parameters
            stepTime=app.StepTimesecEditField.Value;
            global LengthThres;
            LengthThres=round(app.ShortestsoundboutsEditField.Value/stepTime);
            global stepTime_track;
            stepTime_track=stepTime;
            global SignalZscoreThreshold;
            SignalZscoreThreshold=app.FundamentalmeanZscorethresholdEditField.Value;
            global signalRemovalGaussianFilter;
            signalRemovalGaussianFilter=[app.SignalremovalGaussianfilterfrequencyEditField.Value app.SignalremovalGaussianfiltertimeEditField.Value];
            global max_HZ_for_search;
            max_HZ_for_search=app.MaxFrequencyHZEditField.Value;
            global higherSignalZscoreThreshold;
            higherSignalZscoreThreshold=app.OtherpeaksmeanZscorethresholdEditField.Value;
            global IntervalThres;
            IntervalThres=round(app.RemoveintervalsmallerthansEditField.Value/stepTime);
            %% searching
            HighFreRange=300;
            TrackGroup_adjusted=IterativelyShortestDistanceCircle_Start(app.SpectrumAlongTime_normalize,HighFreRange);
            %% arrange result
            [app.parameters,app.RMSEachPeak,TrackGroup_adjusted]=GetParameters(TrackGroup_adjusted,app.SpectrumAlongTime_normalize,app.RMS);
            
            [~,~,~]=GetParameters_test(TrackGroup_adjusted,app.SpectrumAlongTime_normalize,app.SpectrumAlongTime);


            %% plot
            cla(app.TrackingAxes,'reset');
            
            colorSequence={[1 0 0],	[1 0 1],[0 1 0],[0 0 1]};

            
            app.TrackingAxes.XLim = [0 length(app.t)/100];
            app.TrackingAxes.YLim = [0 app.MaxFrequencyHZEditField.Value];           
            hold(app.TrackingAxes,'on');
            global max_peak_num;
            
%             for TrackCnt=1:length(TrackGroup_adjusted)
%                 ThisTrackFum=TrackGroup_adjusted{TrackCnt};
%                 Time=ThisTrackFum(1,:); FumFre=ThisTrackFum(2,:);
%                 for peakCnt=1:max_peak_num
%                     ThisFre=round(FumFre*peakCnt);
%                     if max(ThisFre)>max_HZ_for_search
%                         break;
%                     end
%                     idx=sub2ind(size(app.SpectrumAlongTime_normalize),ThisFre,Time);
%                     PeakMeanZ=mean(app.SpectrumAlongTime_normalize(idx));
%                 end
%             end
            
            for TrackCnt=1:length(TrackGroup_adjusted)
                ThisTrackFum=TrackGroup_adjusted{TrackCnt};
                Time=ThisTrackFum(1,:); FumFre=ThisTrackFum(2,:);
                
                % get max mean z score
                PeakMean_max=0;
                for peakCnt=1:max_peak_num
                    ThisFre=round(FumFre*peakCnt);
                    if max(ThisFre)>max_HZ_for_search
                        break;
                    end
                    PeakMean=app.RMSEachPeak(TrackCnt,peakCnt);
                    if PeakMean>PeakMean_max
                        PeakMean_max=PeakMean;
                    end
                end
                
                for peakCnt=1:max_peak_num
                    ThisFre=round(FumFre*peakCnt);
                    if max(ThisFre)>max_HZ_for_search
                        break;
                    end
                    idx=sub2ind(size(app.SpectrumAlongTime_normalize),ThisFre,Time);
                    PeakMeanZ=mean(app.SpectrumAlongTime_normalize(idx));
                    if PeakMeanZ>higherSignalZscoreThreshold % || peakCnt==1
                        color_org=colorSequence{mod(TrackCnt,length(colorSequence))+1};
                        PeakMean=app.RMSEachPeak(TrackCnt,peakCnt);
                        color=color_org+([1 1 1]-color_org)*(1-PeakMean/PeakMean_max);
                        plot(app.TrackingAxes,Time/100,ThisFre,'Color',color...
                            ,'LineWidth',3);
                    end
                end
            end
            
            
            hold(app.TrackingAxes,'off');
            
            
            %%
            app.Statues.Text="Ready";
            app.Statues.FontColor=[0,0,0];
        end

        % Button pushed function: SaveresultButton
        function SaveresultButtonPushed(app, event)
            
            resultPath=uigetdir;
            drawnow;
            figure(app.SoundAnalysisUIFigure);
            if(resultPath==0)
                %% statue
                app.Statues.Text="Ready";
                app.Statues.FontColor=[0,0,0];
                return;
            end
            cd(resultPath);
            if exist(app.ParameterfilenameEditField.Value,'file')
                delete(app.ParameterfilenameEditField.Value);
            end
            
            if exist(app.AmplitudefilenameEditField.Value,'file')
                delete(app.AmplitudefilenameEditField.Value);
            end
            
            header={'start time (sec)','end time (sec)','bout time (sec)','peak #'...
                ,'mean F0 (HZ)','std of F0 (HZ)','speed of F0 (HZ/sec)','max-start (HZ)',...
                'max-end (HZ)'};
            Table=[header ; num2cell(app.parameters)];
            xlswrite(app.ParameterfilenameEditField.Value,Table);
            
            header2={'Peak 1 (dBSPL)'};
            global max_peak_num;
            for cnt=2:max_peak_num
                header2=[header2,convertStringsToChars("Peak "+cnt+" (dBSPL)")];
            end
            
            celldB=num2cell(RMS2dBSPL(app.RMSEachPeak));
            idx=find(app.RMSEachPeak==0);
            for idxCnt=1:length(idx)
                celldB{idx(idxCnt)}="NAN";
            end
            Table2=[header2 ;celldB];
            xlswrite(app.AmplitudefilenameEditField.Value,Table2);
            
            
        end

        % Button pushed function: CropAudioButton
        function CropAudioButtonPushed(app, event)
            cropStartTime=app.CroppingstarttimesEditField.Value;
            cropEndTime=app.CroppingendtimesEditField.Value;
            
            L = length(app.y);
            cropStartPoint=max(round(cropStartTime*app.Fs),1);
            cropEndPoint=min(round(cropEndTime*app.Fs),L);
            
            if cropStartPoint<cropEndPoint
                app.Statues.Text="Busy";
                app.Statues.FontColor=[0,0,0];
                pause(0.1);
                
                croppedY=app.y(cropStartPoint:cropEndPoint);
                resultPath=uigetdir;
                drawnow;
                figure(app.SoundAnalysisUIFigure);
                if(resultPath==0)
                    %% statue
                    app.Statues.Text="Ready";
                    app.Statues.FontColor=[0,0,0];
                    return;
                end
                cd(resultPath);
                if exist(app.AudiofilenameEditField.Value,'file')
                    delete(app.AudiofilenameEditField.Value);
                end
                audiowrite(app.AudiofilenameEditField.Value,croppedY,app.Fs);
                %%
                app.Statues.Text="Ready";
                app.Statues.FontColor=[0,0,0];
            else
                app.Statues.Text="Error: End Time must be bigger than start time!";
                app.Statues.FontColor=[1,0,0];
                pause(0.1);
            end
        end

        % Value changed function: BoutselectionSpinner
        function BoutselectionSpinnerValueChanged(app, event)
            value=app.BoutselectionSpinner.Value;
            
            BoutNum=length(app.parameters(:,1));
            if value>BoutNum
                app.Statues.Text="Error: Bout # can't be bigger than "+BoutNum+" !";
                app.Statues.FontColor=[1,0,0];
            elseif value<1
                app.Statues.Text="Error: Bout # can't be smaller than 1 !";
                app.Statues.FontColor=[1,0,0];
            else
                app.CroppingstarttimesEditField.Value=app.parameters(value,1);
                app.CroppingendtimesEditField.Value=app.parameters(value,2);
                app.Statues.Text="Ready";
                app.Statues.FontColor=[0,0,0];
            end
        end

        % Button pushed function: FindBackgroundButton
        function FindBackgroundButtonPushed(app, event)
            %% statue
            app.Statues.Text="Busy: get parameters";
            app.Statues.FontColor=[0,0,0];
            
            %% reset axes
            cla(app.BackgroundAxes,'reset')
            cla(app.NormalizedSpectrumAxes,'reset')
            cla(app.TrackingAxes,'reset')
            
            %% Get Parameters
            min_HZ=app.MinFrequencyHZEditField.Value;
            max_HZ=app.MaxFrequencyHZEditField.Value;
            sampleTime=app.SampleTimesecEditField.Value;
            stepTime=app.StepTimesecEditField.Value;
            Background_shrink_time=app.BackgroundshirinkagesEditField.Value;
            global Background_shrink;
            Background_shrink=round(Background_shrink_time/stepTime);
            
            T = 1/app.Fs;             % Sampling period       
            L = length(app.y);        % Length of signal
            time = (0:L-1)*T;        % Time vector
            
            
            %% Get amplitude along time
            app.Statues.Text="Busy: get background";
            pause(0.1);
            AmpAlongTime=sum(app.SpectrumAlongTime(300:end,:),1);
            AmpAlongTime_smooth=localMin(AmpAlongTime,sampleTime,stepTime);
            %% Get Background Idx
            thres=app.BackgroundThresEditField.Value;
            BoutIdx_back=AmpAlongTime_smooth>thres;
            %% normalize spectrum
            app.Statues.Text="Busy: normalize spectrum";
            pause(0.1);
            app.SpectrumAlongTime_normalize=normalizeSpectrum(app.SpectrumAlongTime,BoutIdx_back);
            app.t=(1:length(AmpAlongTime_smooth))*time(end)/length(AmpAlongTime_smooth);


            %% plot normalized spectrum
            Xticklabels_ruler=0:app.t(end)/10:app.t(end);
            app.I = imagesc(mat2gray(flipud(app.SpectrumAlongTime_normalize)), 'Parent', app.NormalizedSpectrumAxes, ...
        'XData', [1 app.SpectrumAxes.Position(3)], ...
        'YData', [1 app.SpectrumAxes.Position(4)]);
            
            app.NormalizedSpectrumAxes.XLim = [0 app.I.XData(2)];
            app.NormalizedSpectrumAxes.YLim = [0 app.I.YData(2)];
            
            app.NormalizedSpectrumAxes.XTick = linspace(1, app.I.XData(2), numel(Xticklabels_ruler));
            app.NormalizedSpectrumAxes.XTickLabel=Xticklabels_ruler;
            app.NormalizedSpectrumAxes.YTick = linspace(1, app.I.YData(2), 11);
            app.NormalizedSpectrumAxes.YTickLabel=max_HZ:-(max_HZ)/10:0;
            
            %% plot background
            plot(app.BackgroundAxes,app.t,AmpAlongTime_smooth);
            hold(app.BackgroundAxes,'on');
            plot(app.BackgroundAxes,app.t,ones(size(AmpAlongTime_smooth))*thres);
            plot(app.BackgroundAxes,app.t,BoutIdx_back.*AmpAlongTime_smooth)
            hold(app.BackgroundAxes,'off');
            

            app.BackgroundAxes.XTick = linspace(1, app.I.XData(2), numel(Xticklabels_ruler));
            app.BackgroundAxes.XTickLabel=Xticklabels_ruler;
            
            %% statue
            app.Statues.Text="Ready";
            app.Statues.FontColor=[0,0,0];
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.SpectrumAxes,'reset')
            cla(app.BackgroundAxes,'reset')
            cla(app.NormalizedSpectrumAxes,'reset')
            cla(app.TrackingAxes,'reset')
            
            app.MaxFrequencyHZEditField.Editable='on';
            app.MinFrequencyHZEditField.Editable='on';
            app.SampleTimesecEditField.Editable='on';
            app.StepTimesecEditField.Editable='on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SoundAnalysisUIFigure = uifigure('Visible', 'off');
            app.SoundAnalysisUIFigure.Name = 'Sound Analysis';
            app.LoadDataButton = uibutton(app.SoundAnalysisUIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.MinFrequencyHZEditFieldLabel = uilabel(app.SoundAnalysisUIFigure);
            app.MinFrequencyHZEditFieldLabel.Text = 'Min Frequency (HZ)';
            app.MinFrequencyHZEditField = uieditfield(app.SoundAnalysisUIFigure, 'numeric');
            app.MinFrequencyHZEditField.Value = 1;
            app.MaxFrequencyHZEditFieldLabel = uilabel(app.SoundAnalysisUIFigure);
            app.MaxFrequencyHZEditFieldLabel.Text = 'Max Frequency (HZ)';
            app.MaxFrequencyHZEditField = uieditfield(app.SoundAnalysisUIFigure, 'numeric');
            app.MaxFrequencyHZEditField.Value = 3000;
            app.SampleTimesecEditFieldLabel = uilabel(app.SoundAnalysisUIFigure);
            app.SampleTimesecEditFieldLabel.Text = 'Sample Time (sec)';
            app.SampleTimesecEditField = uieditfield(app.SoundAnalysisUIFigure, 'numeric');
            app.SampleTimesecEditField.Value = 0.2;
            app.StepTimesecEditFieldLabel = uilabel(app.SoundAnalysisUIFigure);
            app.StepTimesecEditFieldLabel.Text = 'Step Time (sec)';
            app.StepTimesecEditField = uieditfield(app.SoundAnalysisUIFigure, 'numeric');
            app.StepTimesecEditField.Value = 0.01;
            app.BackgroundThresEditFieldLabel = uilabel(app.SoundAnalysisUIFigure);
            app.BackgroundThresEditFieldLabel.Text = 'Background Thres';
            app.BackgroundThresEditField = uieditfield(app.SoundAnalysisUIFigure, 'numeric');
            app.BackgroundThresEditField.Value = 0.013;
            app.TabGroup = uitabgroup(app.SoundAnalysisUIFigure);
            app.SpectrumTab = uitab(app.TabGroup);
            app.SpectrumTab.Title = 'Spectrum';
            app.SpectrumAxes = uiaxes(app.SpectrumTab);
            title(app.SpectrumAxes, 'Spectrum')
            xlabel(app.SpectrumAxes, 'Time')
            ylabel(app.SpectrumAxes, 'Frequency')
            app.BackgroundTab = uitab(app.TabGroup);
            app.BackgroundTab.Title = 'Background';
            app.BackgroundAxes = uiaxes(app.BackgroundTab);
            title(app.BackgroundAxes, 'Normalized Spectrum')
            xlabel(app.BackgroundAxes, 'Time')
            ylabel(app.BackgroundAxes, 'Amplitude')
            app.NormalizedspectrumTab = uitab(app.TabGroup);
            app.NormalizedspectrumTab.Title = 'Normalized spectrum';
            app.NormalizedSpectrumAxes = uiaxes(app.NormalizedspectrumTab);
            title(app.NormalizedSpectrumAxes, 'Normalized Spectrum')
            xlabel(app.NormalizedSpectrumAxes, 'Time')
            ylabel(app.NormalizedSpectrumAxes, 'Frequency')
            app.TrackingTab = uitab(app.TabGroup);
            app.TrackingTab.Title = 'Tracking';
            app.TrackingAxes = uiaxes(app.TrackingTab);
            title(app.TrackingAxes, 'Tracking')
            xlabel(app.TrackingAxes, 'Time')
            ylabel(app.TrackingAxes, 'Frequency')
            app.Statues = uilabel(app.SoundAnalysisUIFigure);
            app.Statues.Text = 'Ready';
            app.SearchingButton = uibutton(app.SoundAnalysisUIFigure, 'push');
            app.SearchingButton.ButtonPushedFcn = createCallbackFcn(app, @SearchingButtonPushed, true);
            app.SearchingButton.Text = 'Searching';
            app.SaveresultButton = uibutton(app.SoundAnalysisUIFigure, 'push');
            app.SaveresultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultButtonPushed, true);
            app.SaveresultButton.Text = 'Save result';
            app.ParameterfilenameEditFieldLabel = uilabel(app.SoundAnalysisUIFigure);
            app.ParameterfilenameEditFieldLabel.Text = 'Parameter file name';
            app.ParameterfilenameEditField = uieditfield(app.SoundAnalysisUIFigure, 'text');
            app.ParameterfilenameEditField.Value = 'result.xlsx';
            app.AmplitudefilenameEditFieldLabel = uilabel(app.SoundAnalysisUIFigure);
            app.AmplitudefilenameEditFieldLabel.Text = 'Amplitude file name';
            app.AmplitudefilenameEditField = uieditfield(app.SoundAnalysisUIFigure, 'text');
            app.AmplitudefilenameEditField.Value = 'result_2.xlsx';
            app.CropAudioButton = uibutton(app.SoundAnalysisUIFigure, 'push');
            app.CropAudioButton.ButtonPushedFcn = createCallbackFcn(app, @CropAudioButtonPushed, true);
            app.CropAudioButton.Text = 'Crop Audio';
            app.CroppingstarttimesEditFieldLabel = uilabel(app.SoundAnalysisUIFigure);
            app.CroppingstarttimesEditFieldLabel.Text = 'Cropping start time (s)';
            app.CroppingstarttimesEditField = uieditfield(app.SoundAnalysisUIFigure, 'numeric');
            app.CroppingendtimesEditFieldLabel = uilabel(app.SoundAnalysisUIFigure);
            app.CroppingendtimesEditFieldLabel.Text = 'Cropping end time (s)';
            app.CroppingendtimesEditField = uieditfield(app.SoundAnalysisUIFigure, 'numeric');
            app.AudiofilenameEditFieldLabel = uilabel(app.SoundAnalysisUIFigure);
            app.AudiofilenameEditFieldLabel.Text = 'Audio file name';
            app.AudiofilenameEditField = uieditfield(app.SoundAnalysisUIFigure, 'text');
            app.AudiofilenameEditField.Value = 'CroppedAudio.wav';
            app.BoutselectionSpinnerLabel = uilabel(app.SoundAnalysisUIFigure);
            app.BoutselectionSpinnerLabel.Text = 'Bout selection';
            app.BoutselectionSpinner = uispinner(app.SoundAnalysisUIFigure);
            app.BoutselectionSpinner.ValueChangedFcn = createCallbackFcn(app, @BoutselectionSpinnerValueChanged, true);
            app.MainParametersLabel = uilabel(app.SoundAnalysisUIFigure);
            app.MainParametersLabel.Text = 'Main Parameters';
            app.SavexlsxLabel = uilabel(app.SoundAnalysisUIFigure);
            app.SavexlsxLabel.Text = 'Save xlsx';
            app.SaveCroppedaudioLabel = uilabel(app.SoundAnalysisUIFigure);
            app.SaveCroppedaudioLabel.Text = 'Save Cropped audio';
            app.ProcessbuttonLabel = uilabel(app.SoundAnalysisUIFigure);
            app.ProcessbuttonLabel.Text = 'Process button';
            app.FiguresLabel = uilabel(app.SoundAnalysisUIFigure);
            app.FiguresLabel.Text = 'Figures';
            app.FindBackgroundButton = uibutton(app.SoundAnalysisUIFigure, 'push');
            app.FindBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @FindBackgroundButtonPushed, true);
            app.FindBackgroundButton.Text = 'Find Background';
            app.OtherParametersLabel = uilabel(app.SoundAnalysisUIFigure);
            app.OtherParametersLabel.Text = 'Other Parameters';
            app.BackgroundshirinkagesEditFieldLabel = uilabel(app.SoundAnalysisUIFigure);
            app.BackgroundshirinkagesEditFieldLabel.Text = 'Background shirinkage (s)';
            app.BackgroundshirinkagesEditField = uieditfield(app.SoundAnalysisUIFigure, 'numeric');
            app.BackgroundshirinkagesEditField.Value = 0.5;
            app.ShortestsoundboutsEditFieldLabel = uilabel(app.SoundAnalysisUIFigure);
            app.ShortestsoundboutsEditFieldLabel.Text = 'Shortest sound bout (s)';
            app.ShortestsoundboutsEditField = uieditfield(app.SoundAnalysisUIFigure, 'numeric');
            app.ShortestsoundboutsEditField.Value = 1;
            app.FundamentalmeanZscorethresholdEditFieldLabel = uilabel(app.SoundAnalysisUIFigure);
            app.FundamentalmeanZscorethresholdEditFieldLabel.Text = 'Fundamental mean Z score threshold';
            app.FundamentalmeanZscorethresholdEditField = uieditfield(app.SoundAnalysisUIFigure, 'numeric');
            app.FundamentalmeanZscorethresholdEditField.Value = 5;
            app.SignalremovalGaussianfilterfrequencyEditFieldLabel = uilabel(app.SoundAnalysisUIFigure);
            app.SignalremovalGaussianfilterfrequencyEditFieldLabel.Text = 'Signal removal Gaussian filter (frequency)';
            app.SignalremovalGaussianfilterfrequencyEditField = uieditfield(app.SoundAnalysisUIFigure, 'numeric');
            app.SignalremovalGaussianfilterfrequencyEditField.Value = 5;
            app.SignalremovalGaussianfiltertimeEditFieldLabel = uilabel(app.SoundAnalysisUIFigure);
            app.SignalremovalGaussianfiltertimeEditFieldLabel.Text = 'Signal removal Gaussian filter (time)';
            app.SignalremovalGaussianfiltertimeEditField = uieditfield(app.SoundAnalysisUIFigure, 'numeric');
            app.SignalremovalGaussianfiltertimeEditField.Value = 3;
            app.OtherpeaksmeanZscorethresholdEditFieldLabel = uilabel(app.SoundAnalysisUIFigure);
            app.OtherpeaksmeanZscorethresholdEditFieldLabel.Text = 'Other peaks mean Z score threshold';
            app.OtherpeaksmeanZscorethresholdEditField = uieditfield(app.SoundAnalysisUIFigure, 'numeric');
            app.OtherpeaksmeanZscorethresholdEditField.Value = 3;
            app.ResetButton = uibutton(app.SoundAnalysisUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RemoveintervalsmallerthansEditFieldLabel = uilabel(app.SoundAnalysisUIFigure);
            app.RemoveintervalsmallerthansEditFieldLabel.Text = 'Remove interval smaller than (s)';
            app.RemoveintervalsmallerthansEditField = uieditfield(app.SoundAnalysisUIFigure, 'numeric');
            app.RemoveintervalsmallerthansEditField.Value = 0.1;
            % Show the figure after all components are created
            app.SoundAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SoundAnalysisV6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SoundAnalysisUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SoundAnalysisUIFigure)
        end
    end
end